classdef exo_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        StartButton                   matlab.ui.control.Button
        StopButton                    matlab.ui.control.Button
        StatusEditFieldLabel          matlab.ui.control.Label
        StatusEditField               matlab.ui.control.EditField
        ShowButton                    matlab.ui.control.Button
        OKButton                      matlab.ui.control.Button
        OptimizationlowerlimitEditFieldLabel  matlab.ui.control.Label
        Optimizationlowerlim          matlab.ui.control.NumericEditField
        OptimizationupperlimitEditFieldLabel  matlab.ui.control.Label
        Optimizationupperlim          matlab.ui.control.NumericEditField
        NumberofframesEditFieldLabel  matlab.ui.control.Label
        Numberofframes                matlab.ui.control.NumericEditField
        DataselectButtonGroup         matlab.ui.container.ButtonGroup
        NewpointButton                matlab.ui.control.RadioButton
        ExistingPointButton           matlab.ui.control.RadioButton
        SaveDataCheckBox              matlab.ui.control.CheckBox
    end

properties (Access = public)
    main_data;
    mask1;
    mask2;
    xpos;
    ypos;
    ok_execute=1;%controls execution of the fig window to position the mask and select the region of intersection
    h_m1;%this is handle for the 1st mask
    h_m2;%this is handle for the 2nd mask
    h_fig;%this is the handle for the figure created outside the app designer window 
    plot_point;
end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.StopButton.Visible='off';
            app.StartButton.Visible='on';
            %app.DataanalysistypeButtonGroup.Visible='on';
            %app.ChoosePSFDropDown.Visible='off';
            %app.ChoosePSFtypeDropDown.Visible='off';
            app.ShowButton.Visible='off';
            app.OKButton.Visible='off';
            app.Numberofframes.Visible='off';
            app.DataselectButtonGroup.Visible='off';
            app.Optimizationlowerlim.Visible='off';
            app.Optimizationupperlim.Visible='off';
            app.OptimizationlowerlimitEditFieldLabel.Visible='off';
            app.OptimizationupperlimitEditFieldLabel.Visible='off';
            app.NumberofframesEditFieldLabel.Visible='off';
            app.SaveDataCheckBox.Visible='off';
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
          uiresume; 
         switch app.ok_execute
            case 1
           % ax=app.UIAxes.CreateFcn;    
            app.StatusEditField.Value='Reshape the mask region for the top PSF (use red circle) and click OK';
            app.h_fig=figure(1);
            scr_size=get(groot,'Screensize');
            imagesc(log(double(imread('calibration_image.tiff'))))
            set(app.h_fig,'Position',[scr_size(3)/2,1,scr_size(3)/2,scr_size(4)]);
            colormap(app.h_fig.CurrentAxes,'gray');
            %h=drawcircle(ha.CurrentAxes);
            app.h_m1=imellipse(app.h_fig.CurrentAxes,[10,10,100,100]);
           %     h=images.roi.Circle(app.UIAxes,'Center',[100,100],'Color','r','Radius',50);
            setColor(app.h_m1,'r');
            setFixedAspectRatioMode(app.h_m1,true);
            app.ok_execute=2;
            uiwait(app.h_fig);
             case 2
                 app.StatusEditField.Value='Place the mask on the bottom PSF (use green circle) and click OK';
                 v=app.h_m1.getPosition;
                 app.h_m2=imellipse(app.h_fig.CurrentAxes,[10,10,v(3),v(4)]);
           %     h=images.roi.Circle(app.UIAxes,'Center',[100,100],'Color','r','Radius',50);
                 setColor(app.h_m2,'g');
                 setResizable(app.h_m2,false);
                 setResizable(app.h_m1,false);
                 app.ok_execute=3;
                 uiwait(app.h_fig);
             case 3
            %elseif app.TaskexecutionButtonGroup.Children(2).Value
                app.StatusEditField.Value='Mask positions stored. Select coinciding point for PSF. Then press ENTER button';
                app.mask1=app.h_m1.getPosition; 
                app.mask2=app.h_m2.getPosition;
                [x,y]=getpts(app.h_fig.CurrentAxes);
                app.ypos=x;
                app.xpos=y;
                app.ok_execute=4;
                app.StatusEditField.Value='Enter optimization values and click on OK to beging data reduction';
                app.Optimizationlowerlim.Visible='on';
                app.Optimizationupperlim.Visible='on';
                app.OptimizationlowerlimitEditFieldLabel.Visible='on';
                app.OptimizationupperlimitEditFieldLabel.Visible='on';
                uiwait(app.h_fig);
             case 4
                 app.ok_execute=1;
                 clear app.h_m1 app.h_m2
                 close(app.h_fig);
                app.StatusEditField.Value='The mask and coinciding point is obtained. Continuing execution...';
                pause(1);
                app.OKButton.Visible='off';
                c1=app.mask1;
                rads=c1(3)/2;
                c1=c1(1:2)+rads;
                c2=app.mask2;
                c2=c2(1:2)+rads;
                dis=sqrt((c1(1)-c2(1))^2+(c1(2)-c2(2))^2);
                th=atan2d((c2(2)-c1(2)),(c2(1)-c1(1)))-90;
                [x,y]=size(imread('calibration_image.tiff'));
                x=round(x/2);y=round(y/2);

                new_c1=c1+[x-app.xpos,y-app.ypos];
                new_c2=c2+[x-app.xpos,y-app.ypos];
                out1=[1,0,x;0,1,y;0,0,1]*[cosd(th),-sind(th),0;sind(th),cosd(th),0;0,0,1]*[1,0,-x;0,1,-y;0,0,1]*[new_c1(1);new_c1(2);1];
                out2=[1,0,x;0,1,y;0,0,1]*[cosd(th),-sind(th),0;sind(th),cosd(th),0;0,0,1]*[1,0,-x;0,1,-y;0,0,1]*[new_c2(1);new_c2(2);1];
                new_c1=out1(1:2);
                new_c2=out2(1:2);
                
                %circshift(im,[x-app.xpos,y-app.ypos]);%use this in every image to correct for shift
                %imrotate(im,th);
                
%                 app.StatusEditField.Value='Reading original PSF';
%                 cd('.\original_psf\');
%                 a=dir('*.tiff');
%                 c=double(imread('1.tiff'));
%               for i=2:length(a)
%                   c=c+double(imread([num2str(i),'.tiff']));
%               end
%               c=c/length(a);
%               cd('..');
%                            
%               o_psf=log(c);%the image of original psf 
%               original_psf=c(1147,966:1519);%the plot of original psf
%               
%             
%               app.StatusEditField.Value='Reading coronagraph PSF';
%               cd('.\coronagraph_only\');
%               a=dir('*.tiff');
%               c=double(imread('1.tiff'));
%               for i=2:length(a)
%                   c=c+double(imread([num2str(i),'.tiff']));
%               end
%               c=c/length(a);
%               cd('..');
%                            
%               cor_psf=log(c);%the image of coronagraph psf 
%               coronagraph_only=c(1147,976:1529);%the plot of coronagraph psf
%               
%               %---------------------------circularmask------------------------------------%
%               app.StatusEditField.Value='Reading polarimeter PSF';
%               mask=createCirclesMask(zeros(1130,1083),[509,542],117);
%               cd('.\pol_only\');
%               a1=zeros(1130,1083);
%               a2=zeros(1108,1083);
%               single1=imresize(a1,1/3);
%               single2=single1;
%               dualb=single1;
%               aa1=a1;
%               aa2=a2;
%               cd('.\+q\');
%               a=dir('*.tiff');
%               for i=1:length(a)
%               c=double(imread([num2str(i),'.tiff']));
%               aa1=c(220:1349,630:1712);
%               aa2=c(1348:2455,630:1712);
%               cd('..\-q');
%               c=double(imread([num2str(i),'.tiff']));
%               a1=c(220:1349,630:1712);
%               a2=c(1348:2455,630:1712);
%               a2=imresize(a2,[1130,1083]);
%               aa2=imresize(aa2,[1130,1083]);
%               cd('..\..\');
%               imwrite(uint16(aa1.*~mask),'tmp1.tiff');
%               imwrite(uint16(a1.*~mask),'tmp2.tiff');
%               imwrite(uint16(aa2.*~mask),'tmp_1.tiff');
%               imwrite(uint16(a2.*~mask),'tmp_2.tiff');
%               single1=imresize(aa1-fmincon(@exo_min,1,1,1,1,1,app.Optimizationlowerlim.Value,app.Optimizationupperlim.Value)*a1,1/3)+single1;
%               single2=imresize(a2-fmincon(@exo_min1,1,1,1,1,1,app.Optimizationlowerlim.Value,app.Optimizationupperlim.Value)*aa2,1/3)+single2;
%               dualb=(single1-single2)+dualb;
%               cd('.\pol_only\+q\');
%               end
%               pol_only=dualb;
%               pol_only_psf=dualb(181,171:361);
%               cd('..\..\');


%               app.StatusEditField.Value='Reading pol_cor PSF';
%               mask=createCirclesMask(zeros(1130,1083),[509,542],117);
%               cd('.\pol_and_coronagraph\');
%               a1=zeros(1130,1083);
%               a2=zeros(1108,1083);
%               single1=imresize(a1,1/3);
%               single2=single1;
%               dualb=single1;
%               aa1=a1;
%               aa2=a2;
%               cd('.\+q\');
%               a=dir('*.tiff');
%               for i=1:length(a)
%               c=double(imread([num2str(i),'.tiff']));
%               aa1=c(220:1349,630:1712);
%               aa2=c(1348:2455,630:1712);
%               cd('..\-q');
%               c=double(imread([num2str(i),'.tiff']));
%               a1=c(220:1349,630:1712);
%               a2=c(1348:2455,630:1712);
%               a2=imresize(a2,[1130,1083]);
%               aa2=imresize(aa2,[1130,1083]);
%               cd('..\..\');
%               imwrite(uint16(aa1.*~mask),'tmp1.tiff');
%               imwrite(uint16(a1.*~mask),'tmp2.tiff');
%               imwrite(uint16(aa2.*~mask),'tmp_1.tiff');
%               imwrite(uint16(a2.*~mask),'tmp_2.tiff');
%               single1=imresize(aa1-fmincon(@exo_min,1,1,1,1,1,app.Optimizationlowerlim.Value,app.Optimizationupperlim.Value)*a1,1/3)+single1;
%               single2=imresize(a2-fmincon(@exo_min1,1,1,1,1,1,app.Optimizationlowerlim.Value,app.Optimizationupperlim.Value)*aa2,1/3)+single2;
%               %single1=imresize(aa1-1*a1,1/3)+single1;
%               %single2=imresize(a2-1*aa2,1/3)+single2;
%               dualb=(single1-single2)+dualb;
%               cd('.\pol_and_coronagraph\+q\');
%               end
%               pol_cor=dualb;
%               pol_cor_psf=dualb(181,171:361);
%               cd('..\..\');


              im=imread('calibration_image.tiff');
              im=circshift(im,[x-round(app.xpos),y-round(app.ypos)]);%use this in every image to correct for shift
              sz=size(imrotate(im,th));
              roi_im1_1=[x-round(dis),y-round(dis/2)];%lower point of the 1st ROI
              roi_im1_2=[x,y+round(dis/2)];%upper point of the 1st ROI
              roi_im2_1=[x,y-round(dis/2)];%lower point of 2nd ROI
              roi_im2_2=[x+round(dis),y+round(dis/2)];%upper point of 2nd ROI
              
              m1=createCirclesMask(zeros(sz),(new_c1)',rads);
              m2=createCirclesMask(zeros(sz),new_c2',rads);
              
              %mask=createCirclesMask(zeros(1130,1083),[509,542],117);
              cd('.\pol_and_coronagraph\');
              
              a1=zeros([length(roi_im1_1(1):roi_im1_2(1)),length(roi_im1_1(2):roi_im1_2(2))]);
              a2=a1;
              single1=imresize(a1,1/3);
              single2=single1;
              dualb=single1;
              aa1=a1;
              aa2=a2;
              cd('.\+q\');
              a=dir('*.tiff');
              for i=1:1
              c=double(imread([num2str(i),'.tiff']));
              c=circshift(c,[x-round(app.xpos),y-round(app.ypos)]);
              c=imrotate(c,th);
              im1=c;
              im1(m1)=0;
              im2=c;
              im2(m2)=0;
              aa1=im1((roi_im1_1(1):roi_im1_2(1)),(roi_im1_1(2):roi_im1_2(2)));
              aa2=im2((roi_im2_1(1):roi_im2_2(1)),(roi_im2_1(2):roi_im2_2(2)));
              cd('..\-q');
              c=double(imread([num2str(i),'.tiff']));
              c=circshift(c,[x-round(app.xpos),y-round(app.ypos)]);
              c=imrotate(c,th);
              im1=c;
              im1(m1)=0;
              im2=c;
              im2(m2)=0;
              a1=im1((roi_im1_1(1):roi_im1_2(1)),(roi_im1_1(2):roi_im1_2(2)));
              a2=im2((roi_im2_1(1):roi_im2_2(1)),(roi_im2_1(2):roi_im2_2(2)));
              cd('..\..\');
              imwrite(uint16(aa1),'tmp1.tiff');
              imwrite(uint16(a1),'tmp2.tiff');
              imwrite(uint16(aa2),'tmp_1.tiff');
              imwrite(uint16(a2),'tmp_2.tiff');
              single1=imresize(aa1-fmincon(@exo_min,1,1,1,1,1,app.Optimizationlowerlim.Value,app.Optimizationupperlim.Value)*a1,1/3)+single1;
              single2=imresize(aa2-fmincon(@exo_min1,1,1,1,1,1,app.Optimizationlowerlim.Value,app.Optimizationupperlim.Value)*a2,1/3)+single2;
              %single1=imresize(aa1-1*a1,1/3)+single1;
              %single2=imresize(a2-1*aa2,1/3)+single2;
              dualb=(single1-single2)+dualb;
              cd('.\pol_and_coronagraph\+q\');
              end
              pol_cor=dualb;
              pol_cor_psf=dualb(181,171:361);
              cd('..\..\');
              app.ShowButton.Visible='on';
              app.DataselectButtonGroup.Visible='on';
              app.Numberofframes.Visible='on';
              app.NumberofframesEditFieldLabel.Visible='on';
              app.SaveDataCheckBox.Visible='on';
              app.plot_point=size(aa1)/2; 
              app.StatusEditField.Value='Click on show button';
          end

        end

        % Button pushed function: ShowButton
        function ShowButtonPushed(app, event)
                app.StatusEditField.Value='Select number of images to be used and data point selection. Then click show (close fig the 1st time)';
                %uiwait(app.h_fig);
                im=imresize(double(imread('tmp1.tiff')),1/3);
                
                if app.NewpointButton.Value==true
                    app.StatusEditField.Value='Select a point in the figure to plot the PSF';
                    app.h_fig=figure(1);
                    scr_size=get(groot,'Screensize');
                    set(app.h_fig,'Position',[scr_size(3)/2,1,scr_size(3)/2,scr_size(4)]);
                    imagesc(im)
                    colormap(app.h_fig.CurrentAxes,'gray');
                    [plt_x,plt_y]=getpts;
                    app.plot_point=[plt_x,plt_y];
                end
                    
                c1=app.mask1;
                rads=c1(3)/2;
                c1=c1(1:2)+rads;
                c2=app.mask2;
                c2=c2(1:2)+rads;
                dis=sqrt((c1(1)-c2(1))^2+(c1(2)-c2(2))^2);
                th=atan2d((c2(2)-c1(2)),(c2(1)-c1(1)))-90;
                [x,y]=size(imread('calibration_image.tiff'));
                x=round(x/2);y=round(y/2);

                new_c1=c1+[x-app.xpos,y-app.ypos];
                new_c2=c2+[x-app.xpos,y-app.ypos];
                out1=[1,0,x;0,1,y;0,0,1]*[cosd(th),-sind(th),0;sind(th),cosd(th),0;0,0,1]*[1,0,-x;0,1,-y;0,0,1]*[new_c1(1);new_c1(2);1];
                out2=[1,0,x;0,1,y;0,0,1]*[cosd(th),-sind(th),0;sind(th),cosd(th),0;0,0,1]*[1,0,-x;0,1,-y;0,0,1]*[new_c2(1);new_c2(2);1];
                new_c1=out1(1:2);
                new_c2=out2(1:2);
                

              im=imread('calibration_image.tiff');
              im=circshift(im,[x-round(app.xpos),y-round(app.ypos)]);%use this in every image to correct for shift
              sz=size(imrotate(im,th));
              roi_im1_1=[x-round(dis),y-round(dis/2)];%lower point of the 1st ROI
              roi_im1_2=[x,y+round(dis/2)];%upper point of the 1st ROI
              roi_im2_1=[x,y-round(dis/2)];%lower point of 2nd ROI
              roi_im2_2=[x+round(dis),y+round(dis/2)];%upper point of 2nd ROI
              
              m1=createCirclesMask(zeros(sz),(new_c1)',rads);
              m2=createCirclesMask(zeros(sz),new_c2',rads);
             
              %mask=createCirclesMask(zeros(1130,1083),[509,542],117);
              cd('.\pol_and_coronagraph\');
              
              a1=zeros([length(roi_im1_1(1):roi_im1_2(1)),length(roi_im1_1(2):roi_im1_2(2))]);
              a2=a1;
              single1=imresize(a1,1/3);
              single2=single1;
              dualb=single1;
              aa1=a1;
              aa2=a2;
              cd('.\+q\');
              a=dir('*.tiff');
              app.Numberofframes.Limits=[1,length(a)];
              app.StatusEditField.Value='Data reduction in progress';
              pause(0.2);
              for i=1:app.Numberofframes.Value
              c=double(imread([num2str(i),'.tiff']));
              c=circshift(c,[x-round(app.xpos),y-round(app.ypos)]);
              c=imrotate(c,th);
              im1=c;
              im1(m1)=0;
              im2=c;
              im2(m2)=0;
              aa1=im1((roi_im1_1(1):roi_im1_2(1)),(roi_im1_1(2):roi_im1_2(2)));
              aa2=im2((roi_im2_1(1):roi_im2_2(1)),(roi_im2_1(2):roi_im2_2(2)));
              cd('..\-q');
              c=double(imread([num2str(i),'.tiff']));
              c=circshift(c,[x-round(app.xpos),y-round(app.ypos)]);
              c=imrotate(c,th);
              im1=c;
              im1(m1)=0;
              im2=c;
              im2(m2)=0;
              a1=im1((roi_im1_1(1):roi_im1_2(1)),(roi_im1_1(2):roi_im1_2(2)));
              a2=im2((roi_im2_1(1):roi_im2_2(1)),(roi_im2_1(2):roi_im2_2(2)));
              cd('..\..\');
              imwrite(uint16(aa1),'tmp1.tiff');
              imwrite(uint16(a1),'tmp2.tiff');
              imwrite(uint16(aa2),'tmp_1.tiff');
              imwrite(uint16(a2),'tmp_2.tiff');
              single1=imresize(aa1-fmincon(@exo_min,1,1,1,1,1,app.Optimizationlowerlim.Value,app.Optimizationupperlim.Value)*a1,1/3);%+single1;
              single2=imresize(a2-fmincon(@exo_min1,1,1,1,1,1,app.Optimizationlowerlim.Value,app.Optimizationupperlim.Value)*aa2,1/3);%+single2;
              %single1=imresize(aa1-1*a1,1/3)+single1;
              %single2=imresize(a2-1*aa2,1/3)+single2;
              dualb=(single1-single2)+dualb;
              cd('.\pol_and_coronagraph\+q\');
              end
              pol_cor=dualb;
              pol_cor_psf=dualb(181,171:361);
              cd('..\..\');
              axis(app.h_fig.CurrentAxes,'xy');
              v=app.plot_point;
              app.StatusEditField.Value='Data plotted';
              semilogy(app.h_fig.CurrentAxes,abs(dualb(round(v(1)),:)/app.Numberofframes.Value))
          if app.SaveDataCheckBox.Value==true   
          app.StatusEditField.Value='creating .mat file and saving data';
          %save('coronagraph_data.mat','o_psf','original_psf','cor_psf','coronagraph_only','pol_only','pol_only_psf','pol_cor','pol_cor_psf')
          save('coronagraph_data.mat','pol_cor_psf')
          app.main_data=load('coronagraph_data.mat');
          app.StatusEditField.Value='Ready';
          end
              
              
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
          app.StopButton.Visible='on';
          app.StopButton.Enable='on';
          app.StartButton.Visible='off';
          %app.DataanalysistypeButtonGroup.Visible='off';
          app.OKButton.Visible='on';
          app.StatusEditField.Value='Execution started. Press OK button to begin'; 
%           if app.LoadDataButton.Value
%               app.StatusEditField.Value='Loading data';
%               app.main_data=load('coronagraph_data.mat');
%               app.StatusEditField.Value='Ready';
%               app.ShowButton.Visible='on';
%           else
%               app.OKButton.Visible='on';
%               
%           end
           % app.ChoosePSFDropDown.Visible='on';
            %app.ChoosePSFtypeDropDown.Visible='on';
            %app.ShowButton.Visible='on';
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.StartButton.Visible='on';
            app.StopButton.Visible='off';
            app.delete;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.StatusEditFieldLabel = uilabel(app.UIFigure);
            app.StatusEditFieldLabel.Text = 'Status';
            app.StatusEditField = uieditfield(app.UIFigure, 'text');
            app.StatusEditField.Editable = 'off';
            app.ShowButton = uibutton(app.UIFigure, 'push');
            app.ShowButton.ButtonPushedFcn = createCallbackFcn(app, @ShowButtonPushed, true);
            app.ShowButton.Text = 'Show';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.OptimizationlowerlimitEditFieldLabel = uilabel(app.UIFigure);
            app.OptimizationlowerlimitEditFieldLabel.Text = 'Optimization lower limit';
            app.Optimizationlowerlim = uieditfield(app.UIFigure, 'numeric');
            app.Optimizationlowerlim.Limits = [0.5 1.5];
            app.Optimizationlowerlim.Value = 0.8;
            app.OptimizationupperlimitEditFieldLabel = uilabel(app.UIFigure);
            app.OptimizationupperlimitEditFieldLabel.Text = 'Optimization upper limit';
            app.Optimizationupperlim = uieditfield(app.UIFigure, 'numeric');
            app.Optimizationupperlim.Limits = [0.5 1.5];
            app.Optimizationupperlim.Value = 1.2;
            app.NumberofframesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofframesEditFieldLabel.Text = 'Number of frames';
            app.Numberofframes = uieditfield(app.UIFigure, 'numeric');
            app.Numberofframes.Limits = [1 Inf];
            app.Numberofframes.Value = 1;
            app.DataselectButtonGroup = uibuttongroup(app.UIFigure);
            app.DataselectButtonGroup.Title = 'Data select';
            app.NewpointButton = uiradiobutton(app.DataselectButtonGroup);
            app.NewpointButton.Text = 'New point';
            app.NewpointButton.Value = true;
            app.ExistingPointButton = uiradiobutton(app.DataselectButtonGroup);
            app.ExistingPointButton.Text = 'Existing Point';
            app.SaveDataCheckBox = uicheckbox(app.UIFigure);
            app.SaveDataCheckBox.Text = 'Save Data';
        end
    end

    methods (Access = public)

        % Construct app
        function app = exo_gui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end