classdef fabry_perot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PlotButton                      matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        lam_0EditFieldLabel             matlab.ui.control.Label
        lam_0EditField                  matlab.ui.control.NumericEditField
        lam_startEditFieldLabel         matlab.ui.control.Label
        lam_startEditField              matlab.ui.control.NumericEditField
        lam_endEditFieldLabel           matlab.ui.control.Label
        lam_endEditField                matlab.ui.control.NumericEditField
        wavelength_pointsEditFieldLabel  matlab.ui.control.Label
        wavelength_pointsEditField      matlab.ui.control.NumericEditField
        FSREditFieldLabel               matlab.ui.control.Label
        FSREditField                    matlab.ui.control.NumericEditField
        FWHMEditFieldLabel              matlab.ui.control.Label
        FWHMEditField                   matlab.ui.control.NumericEditField
        Filter_lamEditFieldLabel        matlab.ui.control.Label
        Filter_lamEditField             matlab.ui.control.NumericEditField
        Filter_FWHMEditFieldLabel       matlab.ui.control.Label
        Filter_FWHMEditField            matlab.ui.control.NumericEditField
        PlottingmethodButtonGroup       matlab.ui.container.ButtonGroup
        IndividualButton                matlab.ui.control.RadioButton
        CombinedButton                  matlab.ui.control.RadioButton
        RefractiveindexEditFieldLabel   matlab.ui.control.Label
        RefractiveindexEditField        matlab.ui.control.NumericEditField
        AngleofincidenceEditFieldLabel  matlab.ui.control.Label
        AngleofincidenceEditField       matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            lamo=app.lam_0EditField.Value;
            ls=app.lam_startEditField.Value;
            le=app.lam_endEditField.Value;
            n=app.wavelength_pointsEditField.Value;
            lam=linspace(ls,le,n);
            fsr=app.FSREditField.Value;
            fwhm=app.FWHMEditField.Value;
            fil_lam=app.Filter_lamEditField.Value;
            fil_fwhm=app.Filter_FWHMEditField.Value;
            sig=fil_fwhm/2.35482;
            ri=app.RefractiveindexEditField.Value;
            th=app.AngleofincidenceEditField.Value;
            l=lamo.^2./(2*ri*fsr*cosd(th));%length ofthe cavity
            x=fwhm/fsr;
            x=2+(x^2*pi^2);
            R=(x-sqrt(x.^2-4))/2; %reflectivity ofthe mirror based on FWHM and FSR
            d=(2*pi./lam).*(2*ri.*l.*cosd(th)); 
            t=((1-R).^2)./(1+R.^2-2*R.*cos(d));%traansmission by an FP
            filter_y=exp(-((fil_lam-lam)./(sig)).^2./2);
            
            if app.IndividualButton.Value==true
            cla(app.UIAxes);
            plot(app.UIAxes,lam,t,'b');
            hold(app.UIAxes);
            plot(app.UIAxes,lam,filter_y,'r');
            legend(app.UIAxes,'Fabry perot','Filter');
            else
            cla(app.UIAxes);
            plot(app.UIAxes,lam,filter_y,'b');
            hold(app.UIAxes);
            plot(app.UIAxes,lam,filter_y.*t,'r');
            legend(app.UIAxes,'Filter','Filter and FP');
            end   
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Plots')
            xlabel(app.UIAxes, 'Wavelength')
            ylabel(app.UIAxes, 'Normalized intensity')
            app.lam_0EditFieldLabel = uilabel(app.UIFigure);
            app.lam_0EditFieldLabel.Text = 'lam_0';
            app.lam_0EditField = uieditfield(app.UIFigure, 'numeric');
            app.lam_startEditFieldLabel = uilabel(app.UIFigure);
            app.lam_startEditFieldLabel.Text = 'lam_start';
            app.lam_startEditField = uieditfield(app.UIFigure, 'numeric');
            app.lam_endEditFieldLabel = uilabel(app.UIFigure);
            app.lam_endEditFieldLabel.Text = 'lam_end';
            app.lam_endEditField = uieditfield(app.UIFigure, 'numeric');
            app.wavelength_pointsEditFieldLabel = uilabel(app.UIFigure);
            app.wavelength_pointsEditFieldLabel.Text = 'wavelength_points';
            app.wavelength_pointsEditField = uieditfield(app.UIFigure, 'numeric');
            app.FSREditFieldLabel = uilabel(app.UIFigure);
            app.FSREditFieldLabel.Text = 'FSR';
            app.FSREditField = uieditfield(app.UIFigure, 'numeric');
            app.FWHMEditFieldLabel = uilabel(app.UIFigure);
            app.FWHMEditFieldLabel.Text = 'FWHM';
            app.FWHMEditField = uieditfield(app.UIFigure, 'numeric');
            app.Filter_lamEditFieldLabel = uilabel(app.UIFigure);
            app.Filter_lamEditFieldLabel.Text = 'Filter_lam';
            app.Filter_lamEditField = uieditfield(app.UIFigure, 'numeric');
            app.Filter_FWHMEditFieldLabel = uilabel(app.UIFigure);
            app.Filter_FWHMEditFieldLabel.Text = 'Filter_FWHM';
            app.Filter_FWHMEditField = uieditfield(app.UIFigure, 'numeric');
            app.PlottingmethodButtonGroup = uibuttongroup(app.UIFigure);
            app.PlottingmethodButtonGroup.Title = 'Plotting method';
            app.IndividualButton = uiradiobutton(app.PlottingmethodButtonGroup);
            app.IndividualButton.Text = 'Individual';
            app.IndividualButton.Value = true;
            app.CombinedButton = uiradiobutton(app.PlottingmethodButtonGroup);
            app.CombinedButton.Text = 'Combined';
            app.RefractiveindexEditFieldLabel = uilabel(app.UIFigure);
            app.RefractiveindexEditFieldLabel.Text = 'Refractive index';
            app.RefractiveindexEditField = uieditfield(app.UIFigure, 'numeric');
            app.RefractiveindexEditField.Limits = [0 Inf];
            app.RefractiveindexEditField.Value = 1;
            app.AngleofincidenceEditFieldLabel = uilabel(app.UIFigure);
            app.AngleofincidenceEditFieldLabel.Text = 'Angle of incidence';
            app.AngleofincidenceEditField = uieditfield(app.UIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = fabry_perot

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end